classdef Show_sensor_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Load_DataButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load_DataButton
        function Start_Plot(app, event)
        if evalin('base', 'exist(''sensorData'', ''var'')') && evalin('base', 'exist(''sensorTime'', ''var'')')
           i=2;
             while i<1
                sensorData = evalin('base', 'sensorData');
                sensorTime = evalin('base', 'sensorTime');
                plot(app.UIAxes, sensorTime, sensorData, 'b-', 'LineWidth', 2);
                app.UIAxes.XLabel.String = 'Zeit (s)';
                app.UIAxes.YLabel.String = 'Abstand (cm)';
                app.UIAxes.Title.String = 'Abstandsdaten';
             end
        else
      
            disp('Sensordaten (sensorData, sensorTime) fehlen im Workspace!');
        end
        end

        % Button down function: UIAxes
        function Distance_Data(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @Distance_Data, true);
            app.Load_DataButton = uibutton(app.UIFigure, 'push');
            app.Load_DataButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Plot, true);
            app.Load_DataButton.Text = 'Load_Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_sensor_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end